classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        ImagenButton         matlab.ui.control.Button
        ColoresListBox       matlab.ui.control.ListBox
        ColoresListBoxLabel  matlab.ui.control.Label
        ImagePreview         matlab.ui.control.Image
        UIAxes               matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function img = capture(app)
            cam = webcam('Integrated');
            img = snapshot(cam); 
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImagenButton
        function powerUp(app, event)
            clc; 
            while true

                img = capture(app); 

                resize = 512; 
                threshold = 0.5;
                dims = [512 256 128 64 32];

                tic
                    predictions = app_predict_quadtree(img,resize,threshold, dims, 'ann',app.UIAxes);
                toc

                app.ColoresListBox.Items = unique(predictions(:,2));
                pause(0.5);

            end
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ImagePreview = uiimage(app.UIFigure);
            app.ImagePreview.Tag = 'previewImgs';
            app.ColoresListBoxLabel = uilabel(app.UIFigure);
            app.ColoresListBoxLabel.Text = 'Colores';
            app.ColoresListBox = uilistbox(app.UIFigure);
            app.ImagenButton = uibutton(app.UIFigure, 'push');
            app.ImagenButton.ButtonPushedFcn = createCallbackFcn(app, @powerUp, true);
            app.ImagenButton.Tag = 'btnPowerUp';
            app.ImagenButton.Text = 'Imagen';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end